classdef LiCongYYDS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        observeV                        matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        Lamp_card                       matlab.ui.control.Lamp
        Switch_card                     matlab.ui.control.Switch
        Panel_channel1                  matlab.ui.container.Panel
        Button_selectAlg                matlab.ui.container.ButtonGroup
        MButton                         matlab.ui.control.RadioButton
        TButton                         matlab.ui.control.RadioButton
        Label_5                         matlab.ui.control.Label
        selectChannel                   matlab.ui.control.DropDown
        Button_getV                     matlab.ui.control.StateButton
        Label_6                         matlab.ui.control.Label
        pulse_num                       matlab.ui.control.NumericEditField
        Button_freezeShow               matlab.ui.control.StateButton
        Panel                           matlab.ui.container.Panel
        Label_4                         matlab.ui.control.Label
        samplingRate                    matlab.ui.control.NumericEditField
        EditField_2Label_2              matlab.ui.control.Label
        samplingExtent                  matlab.ui.control.NumericEditField
        Tab_3                           matlab.ui.container.Tab
        Label_7                         matlab.ui.control.Label
        VohTime                         matlab.ui.control.NumericEditField
        usLabel                         matlab.ui.control.Label
        Button_selectCalibrationMethod  matlab.ui.container.ButtonGroup
        Button_CO                       matlab.ui.control.RadioButton
        Button_BP                       matlab.ui.control.RadioButton
        Button_DS                       matlab.ui.control.RadioButton
        startTrain                      matlab.ui.control.StateButton
        Label_8                         matlab.ui.control.Label
        targetSpeed_openLoop            matlab.ui.control.NumericEditField
        rpmLabel                        matlab.ui.control.Label
        Tab_4                           matlab.ui.container.Tab
        Button_selectPidMethod          matlab.ui.container.ButtonGroup
        Button_NO                       matlab.ui.control.RadioButton
        Button_FZ                       matlab.ui.control.RadioButton
        BPButton                        matlab.ui.control.RadioButton
        KpEditFieldLabel                matlab.ui.control.Label
        KpEditField                     matlab.ui.control.NumericEditField
        KiEditFieldLabel                matlab.ui.control.Label
        KiEditField                     matlab.ui.control.NumericEditField
        KdEditFieldLabel                matlab.ui.control.Label
        KdEditField                     matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        targetSpeed_closeLoop           matlab.ui.control.NumericEditField
        rpmLabel_2                      matlab.ui.control.Label
        startCloseLoopControl           matlab.ui.control.StateButton
        UIAxes_sub                      matlab.ui.control.UIAxes
        UIAxes_main                     matlab.ui.control.UIAxes
        TerminalTextAreaLabel           matlab.ui.control.Label
        TerminalTextArea                matlab.ui.control.TextArea
        HzLabel                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        V_Hz                            matlab.ui.control.NumericEditField
        rpmEditFieldLabel               matlab.ui.control.Label
        V_rpm                           matlab.ui.control.NumericEditField
        UIAxes_speedRecord              matlab.ui.control.UIAxes
        Button_stop                     matlab.ui.control.Button
        Button_beginRecordSpeed         matlab.ui.control.StateButton
    end


    properties (Access = private)
        %% 信号采集相关
        Fs % 采样频率
        N %采样长度
        T %时间长度
        channelNow %当前通道号码
        cardState = 0;%采集卡状态
        
        %% 数据处理
        signalChannel %采集卡信号
        cardData % Description
        X %横坐标
        xLimit %横坐标范围
        
        %% 测速
        algs %选中的测速算法 'M' stands for M; 'T' stands for T
        isShowV % 是否测速
        pulsePerR % 每圈发出的脉冲
        Speed_rpm % 记录速度
        rotation %转子的正反转 'P' stands for 正转; 'N' stands for 反转
        recordSpeedState %是否记录历史转速并显示到Axis上
        
        %% 开环控制相关
        trainMethod %标定所使用的方法 'CO' stands  for Common; 'BP' stands for BP network; 'DS' stands for DateSet
        answerTool %标定结果
        COM % 串口号
        sCOM %串口对象
        isTrained %是否已经训练
        PositiveMaxVoh %正向转动时最大速度的高电平时长
        PositiveMinVoh
        NegativeMaxVoh %逆向转动时最大速度的高电平时长
        NegativeMinVoh
        
        %% 闭环控制相关
        pidMethod %闭环控制所用方法 'NO' stands for no method;'FZ' stands for fuzzy control; 'BP' stnads for BP network
        targetSpeed %目标速度
        closeLoopP %P
        closeLoopI %I
        closeLoopD % D
        
        %% 辅助参数
        terminalText %提示文本
        
    end

    methods (Access = private)
    
        function show_channel_signal(app) %显示转速/频率函数
            get_channel_signal(app); %采集信号
            plot_channel_signal(app); %画图函数
            f = TDZCD(app,app.signalChannel.Y);%显示转速/频率
            app.V_Hz.Value = f;
            app.Speed_rpm = f/ app.pulsePerR * 60;
            app.V_rpm.Value = app.Speed_rpm ;
        end


        function get_channel_signal(app)
            refreshT(app)
            app.cardData=USBDAQ_readtwoCH(app.N,app.Fs,1,5);
            if app.channelNow == 1
                col = 1;
            else
                col = 2;
            end
            app.signalChannel.Y=app.cardData(:,col);
            %% 低通滤波器
            %[b,a] = butter(6,0.01*2,'low');
            %app.signalChannel.Y = filtfilt(b,a,app.signalChannel.Y);
            
        end
        
        function plot_channel_signal(app)
            zoom = 0.2;
            xUpper = round(zoom*size(app.X,2));
            plot(app.UIAxes_main,app.X(1:xUpper),app.signalChannel.Y(1:xUpper));
            drawnow;
        end
        
        %% 数据分析
        function f = TDZCD(app,y)
        %Time domain zero crossing detection时域过零检测算法
            f = 0;
            if max(y) - min(y) < 0.2
                return
            end
            y_prime = y - mean(y);
            switch(app.algs)
                case 'M'
                    counter =0;
                    startPointIndex = 0;
                    isRecord_startPointIndex = false;
                    endPointIndex = 0;
                    for i=1:app.N-1
                        if y_prime(i)*y_prime(i+1)< 0
                            if ~isRecord_startPointIndex
                                startPointIndex = i;
                            end
                            counter = counter+1;
                            endPointIndex = i;
                        end
                    end
                    durationTime = (endPointIndex-startPointIndex)/app.Fs;
                    f = (counter-1)/(2*durationTime);
                case 'T'
                    isOdd = 2;
                    last_node = 0;
                    counter = 0;
                    total_gap = 0;
                    for i = 1:app.N-1
                        if y_prime(i)*y_prime(i+1)< 0
                            if isOdd
                                if isOdd == 2
                                    last_node = i;
                                end
                                isOdd = isOdd -1;
                            else
                                gap = i - last_node;
                                counter = counter + 1;
                                total_gap = total_gap + gap;
                                isOdd = 2;
                            end
                        end
                    end
                    if counter == 0
                        f = 0;
                    else
                        f = 1 / (total_gap/counter*1/app.Fs);
                    end
            end
        end
        
        
        %% 转速控制函数
        function send_control_message(app,Voh)
            % 数据预处理
            Voh = floor(Voh);
            switch app.rotation
                case 'P'
                    if Voh < app.PositiveManVoh
                        Voh = app.PositiveManVoh;
                    end
                    if Voh > app.PositiveMinVoh
                        Voh = app.PositiveMinVoh;
                    end
                case 'N'
                    if Voh < app.NegativeMinVoh
                        Voh = app.NegativeMinVoh;
                    end
                    if Voh > app.NegativeMaxVoh
                        Voh = app.NegativeMaxVoh;
                    end
            end
            % 发送指令
            fopen(app.sCom);
            
            upper4 = floor(Voh/256);
            lower4 = mod(Voh/256);
            tx = [ 85 06 251 upper4 lower4 170 ];
            fwrite(app.sCom,tx,'uchar');
            add_terminal_text(app,num2str(tx));
            fclose(app.sCom);
        end
        
        function send_control_message_without_COM_change(app,Voh)
            % 数据预处理
            Voh = floor(Voh);
            switch app.rotation
                case 'P'
                    if Voh < app.PositiveManVoh
                        Voh = app.PositiveManVoh;
                    end
                    if Voh > app.PositiveMinVoh
                        Voh = app.PositiveMinVoh;
                    end
                case 'N'
                    if Voh < app.NegativeMinVoh
                        Voh = app.NegativeMinVoh;
                    end
                    if Voh > app.NegativeMaxVoh
                        Voh = app.NegativeMaxVoh;
                    end
            end
            upper4 = floor(Voh/256);
            lower4 = mod(Voh/256);
            tx = [ 85 06 251 upper4 lower4 170 ];
            fwrite(app.sCom,tx,'uchar');
            add_terminal_text(app,num2str(tx));
        end
        
        %% 开环控制相关函数
        function dataSet = get_data_set(app)
            testTimesEachV = 5;
            gap  = 10;
            row1 = ((app.PositiveMinVoh-app.PositiveMaxVoh)/gap)+1;
            row2 = ((app.NegativeMaxVoh-app.NegativeMixVoh)/gap)+1;
            dataSet = zeros(row1+row2,testTimesPerV+1);
            dataSet(1:row1,1) = linspace(app.PositiveMaxVoh,app.PositiveMinVoh,row1);
            dataSet(row1+1:end,1) = linspace(app.NegativeMixVoh,app.NegativeMaxVoh,row2);
            % 标定正转情况
            app.rotation = 'P';
            for i = 1:row1
                send_control_message(app,app.NegativeMixVoh + gap * (i-1));
                pause(1); %等待转速稳定
                for j = 1 : testTimesEachV
                    dataSet(i,j) = app.Speed_rpm;
                    pause(0.4);
                end
            end 
            % 标定反转情况
            app.rotation = 'N';
            for i = 1:row1
                send_control_message(app,app.NegativeMixVoh + gap * (i-1));
                pause(1); %等待转速稳定
                for j = 1 : testTimesEachV
                    dataSet(i,j) = -app.Speed_rpm; %负号表示反转
                    pause(0.4);
                end
            end 
        end
        
        function processResult = process_data(app,dataSet)
            switch app.trainMethod
                case 'CO'
                    row = size(dataSet,1);
                    processResult = zeros(row,2);
                    processResult(:,1) = dataSet(:,1);
                    for i = 1:row
                        Rs = dataSet(i,2:end);
                        ave = mean(Rs);
                        if max(Rs) - min(Rs) / ave < 0.01
                            processResult(i,2) = ave;
                        else
                            Rs = sort(Rs);
                            processResult(i,2) = Rs(3);
                        end
                    end
                    plot(app.UIAxes_sub,processResult(:,1),processResult(:,2));                   
                case 'BP'
                    [pn, ~] = mapminmax(dataSet(:,2:6)');
                    [tn, outputStr] = mapminmax(dataSet(:,1)');
                    net = newff(pn,tn,[5,7,1]);
                    net.trainParam.show = 10;
                    net.trainParam.epochs = 1000;
                    net.trainParam.lr = 0.05;
                    net.trainParam.goal = 0.65 * 10^(-3);
                    net.divideFcn = '';
                    net = train(net, pn, tn);
                    row = 4*(size(dataSet,1)-1)+1;
                    Voh = linspace(app.minVoh,app.maxVoh,row);
                    speed = sim(net,Voh);
                    speed = mapminmax('reverse',speed,outputStr);
                    processResult = [Voh,speed]';
                    plot(app.UIAxes_sub,Voh,speed);    
            end
        end
        
        
        %%辅助函数
        function refreshT(app)
            app.T = app.N/app.Fs;
        end
        
        function add_terminal_text(app,newLine)
            line_num = size(app.terminalText);
            maxLine = 4;
            if line_num < maxLine
                app.terminalText{end + 1} = newLine;
            else
                for i = 1:maxLine-1
                    app.terminalText{i} = app.terminalText{i+1};
                end
                    app.terminalText{maxLine} = newLine;
            end
            app.TerminalTextArea.Value = app.terminalText;
        end
        
        function x_prime = sigmoid(x)
            x_prime =1/(1+exp(-x));
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% 信号采集初始化
            app.samplingRate.Value = 5120;    %初始化
            app.Fs = app.samplingRate.Value;
            app.samplingExtent.Value = 1024;
            app.N = app.samplingExtent.Value;
            refreshT(app);           
            app.X = linspace(0,app.T,app.N);
            app.signalChannel.Y = zeros(1,app.N);
            app.pulsePerR = 1;
            app.pulse_num.Value = 1;
            app.algs = 'T'; 
            
            %% 控件初始化
            app.Switch_card.ItemsData = {0,1}; %赋采集卡开关的初值
            app.Lamp_card.Color = 'red';
            app.terminalText = {'请开始你的表演'};
            app.TerminalTextArea.Value = app.terminalText;
            app.selectChannel.Items = {'通道1','通道5'};
            app.selectChannel.ItemsData = {1,5};
            plot_channel_signal(app);
            app.V_Hz.Value = TDZCD(app,app.signalChannel.Y);
            app.recordSpeedState = false;
            app.Speed_rpm = 0;
            
            %% 开环控制相关初始化
            app.trainMethod = 'CO';
            app.COM = 'COM4';
            app.sCom = serial(app.COM,'BaudRate',115200);
            app.isTrained = false;
            app.PositiveMaxVoh =  900; %正向转动时最大速度的高电平时长
            app.PositiveMinVoh = 1400;
            app.NegativeMaxVoh = 2100; %逆向转动时最大速度的高电平时长
            app.NegativeMinVoh = 1600;
            app.rotation = 'P';
            
            
            %% 闭环控制相关
            app.closeLoopP = 1;
            app.closeLoopD = 0.1;
            app.closeLoopI = 0.01;
            app.KpEditField.Value = app.closeLoopP;
            app.KiEditField.Value = app.closeLoopI;
            app.KdEditField.Value = app.closeLoopD;
        end

        % Value changed function: Button_freezeShow
        function Button_freezeShowValueChanged(app, event)
            value = app.Button_freezeShow.Value;
        end

        % Value changed function: Button_getV
        function Button_getVValueChanged(app, event)
            app.isShowV = app.Button_getV.Value;
            if app.isShowV ==1
                add_terminal_text(app,'开始测速');
            end
            if app.isShowV == 0
                app.V_Hz.Value = 0;
                add_terminal_text(app,'测速结束');
            end
            if app.cardState == 0
                add_terminal_text(app,'请打开采集卡');
                app.Button_getV.Value = 0;
                app.isShowV = 0;
            end      
            %循环控制，以达到实时更新
 
            while app.isShowV
                show_channel_signal(app);
                pause(0.2);
            end
        end

        % Selection changed function: Button_selectAlg
        function Button_selectAlgSelectionChanged(app, event)
             if app.MButton.Value == true   %算法选择
                 app.algs = 'M';
                 add_terminal_text(app,'M算法测速');
             end
             if app.TButton.Value == true
                 app.algs = 'T';
                 add_terminal_text(app,'T算法测速');
             end
        end

        % Selection changed function: Button_selectCalibrationMethod
        function Button_selectCalibrationMethodSelectionChanged(app, event)
             if app.Button_CO.Value == true   %算法选择
                 app.trainMethod = 'CO';
                 add_terminal_text(app,'普普通通标定法');
             end
             if app.Button_BP.Value == true
                 app.trainMethod = 'BP';
                 add_terminal_text(app,'BP神经网络拟合法');
             end
             if app.Button_DS.Value == true
                 app.trainMethod = 'DS';
                 add_terminal_text(app,'数据集训练法');
             end
        end

        % Selection changed function: Button_selectPidMethod
        function Button_selectPidMethodSelectionChanged(app, event)
             %'NO' stands for no method;'FZ' stands for fuzzy control; 'BP' stnads for BP network
             if app.Button_NO.Value == true   %算法选择
                 app.pidMethod = 'NO';
                 add_terminal_text(app,'pid控制');
             end
             if app.Button_FZ.Value == true
                 app.pidMethod = 'FZ';
                 add_terminal_text(app,'模糊控制');
             end
             if app.BPButton.Value == true
                 app.pidMethod = 'BP';
                 add_terminal_text(app,'基于BP神经网络的PID控制');
             end
        end

        % Button pushed function: Button_stop
        function Button_stopPushed(app, event)

            fopen(sCom);
            % 发送指令
            tx = [ 85 03 252];
            fwrite(app.sCom,tx,'uchar');
            add_terminal_text(app,num2str(tx));
            fclose(sCom);
        end

        % Value changed function: KdEditField
        function KdEditFieldValueChanged(app, event)
            app.closeLoopD = app.KdEditField.Value;           
        end

        % Value changed function: KiEditField
        function KiEditFieldValueChanged(app, event)
            app.closeLoopI = app.KiEditField.Value;
        end

        % Value changed function: KpEditField
        function KpEditFieldValueChanged(app, event)
            app.closeLoopP = app.KpEditField.Value;
        end

        % Value changed function: Switch_card
        function Switch_cardValueChanged(app, event)
            value = app.Switch_card.Value;  %开采集
            
            if (value == 0)
                app.Lamp_card.Color = 'red';
                app.cardState = 0;
                USBDAQ_closecard;
                add_terminal_text(app,'采集卡已关闭');
            else
                app.cardState=USBDAQ_opencard;
                if app.cardState==1
                    disp('采集卡开启成功');
                    add_terminal_text(app,'采集卡开启成功');
                    app.Lamp_card.Color = 'green';
                else
                    disp('采集卡开启失败');
                    add_terminal_text(app,'采集卡开启失败');
                    app.Switch_card.Value = 0;
                end
            end
        end

        % Value changed function: VohTime
        function VohTimeValueChanged(app, event)
            voh = app.VohTime.Value;
            if voh < app.PositiveMaxVoh || (app.PositiveMinVoh < voh && voh < app.PositiveMinVoh) || voh > app.NegativeMaxVoh
                add_terminal_text(app,'超出范围');
                return
            end 
            send_control_message(app,voh)
        end

        % Value changed function: pulse_num
        function pulse_numValueChanged(app, event)
            app.pulsePerR = app.pulse_num.Value;  %齿数
        end

        % Value changed function: samplingExtent
        function samplingExtentValueChanged(app, event)
            
            if app.cardState == 1
                app.samplingExtent.Value = app.N;
                add_terminal_text(app,'请先关闭采集卡')
            else
                app.N = app.samplingExtent.Value;
            end
        end

        % Value changed function: samplingRate
        function samplingRateValueChanged(app, event)
            if app.cardState == 1
                app.samplingRate.Value = app.Fs;
                add_terminal_text(app,'请先关闭采集卡')
            else
                app.Fs = app.samplingRate.Value;
            end
        end

        % Value changed function: selectChannel
        function selectChannelValueChanged(app, event)
            
            app.channelNow = app.selectChannel.Value;
            add_terminal_text(app,['你已经切换到了' num2str(app.channelNow) '通道']);  
        end

        % Value changed function: startCloseLoopControl
        function startCloseLoopControlValueChanged(app, event)
            controlState = app.startCloseLoopControl.Value;
            if controlState == false
                add_terminal_text(app,'闭环控制已结束');
                return
            end
            % 闭环控制开
            % 均已秒为单位
            tS = abs(app.targetSpeed) / 60; % 作用域为本函数的目标速度变量
            preSpeed = app.Speed_rpm / 60;
            preSpeedRecordTime = datetime();
            paues(0.1);
            while controlState
                speedNow = app.Speed_rpm / 60;
                speedRecordTime = datetime();
                dT = seconds(speedRecordTime-preSpeedRecordTime);
                Error = speedNow - tS;
                totalError = Error * dT;
                dError = (speedNow - preSpeed ) / dT;
                switch app.pidMethod
                    case 'NO'
                        Voh = app.closeLoopP * Error + app.closeLoopI * totalError + app.closeLoopD * dError;
                    case 'FZ'
                        %fuzzy_control.fis中设定E与dE都在[-3,3]之间。
                        E = 3 * (sigmoid(app,Error)-0.5);
                        dE = 3 * (sigmoid(app,dError)-0.5);
                        FZmat = readfis('fuzzy_control.fis');
                        deltaPID = evalfis([E,dE],FZmat); %PID参数的修正量(dP,dD,dI)
                        Voh = (app.closeLoopP + deltaPID(1)) * Error + (app.closeLoopI + deltaPID(3)) * totalError + ...
                            (app.closeLoopD + deltaPID(2)) * dError;
                        % 更新控件显示信息
                        app.KpEditField.Value = app.closeLoopP + deltaPID(1);
                        app.KiEditField.Value = app.closeLoopI + deltaPID(3);
                        app.KdEditField.Value = app.closeLoopD + deltaPID(2);      
                end
                switch app.rotation
                    case 'P'
                        Voh = app.PositiveMinVoh - Voh;
                    case 'N'
                        Voh = app.NegativeMinVoh + Voh;
                end
                send_control_message(Voh);
                preSpeed = speedNow;
                preSpeedRecordTime = speedRecordTime;
                paues(0.01); %避免出现dT = 0的情况  
            end
            
        end

        % Value changed function: startTrain
        function startTrainValueChanged(app, event)
            
            startTime = datetime();
            dataSet = get_data_set(app);
            clear app.answerTool;
            app.answerTool = process_data(app,dataSet);
            endTime = datetime();
            add_termianl_text(app,['标定总耗时' num2str(seconds(endTime- startTime)) 'S']);
            app.isTrained = true;
        end

        % Value changed function: targetSpeed_closeLoop
        function targetSpeed_closeLoopValueChanged(app, event)
            app.targetSpeed = app.targetSpeed_closeLoop.Value;
            if app.targetSpeed < 0
                app.rotation = 'N';
            else
                app.rotation = 'P';
            end
            
        end

        % Value changed function: targetSpeed_openLoop
        function targetSpeed_openLoopValueChanged(app, event)
            app.targetSpeed = app.targetSpeed_openLoop.Value;
            if app.targetSpeed < 0
                app.rotation = 'N';
            else
                app.rotation = 'P';
            end
            if ~app.isTrained
                add_terminal_text(app,'请先标定');
                return
            end
            max_speed = max(app.answerTool(:,2));
            min_speed = min(app.answerTool(:,2));
            if target < min_speed || target > max_speed
                add_terminal_text(app,['速度范围为' num2str([min_speed max_speed]) '。']);
                return
            end
            switch app.trainMethod
                case 'CO'
                    % 插值
                    data_x = app.answerTool(:,2);
                    data_y = app.answerTool(:,1);
                    Voh=interp1(data_x,data_y,app.targetSpeed);
                    send_control_message(app,Voh);
                case 'BP'
                    data_x = app.answerTool(:,2);
                    data_y = app.answerTool(:,1);
                    Voh=interp1(data_x,data_y,app.targetSpeed);
                    send_control_message(app,Voh);
                    
            end
        end

        % Value changed function: Button_beginRecordSpeed
        function Button_beginRecordSpeedValueChanged(app, event)
            app.recordSpeedState = app.Button_beginRecordSpeed.Value;
            if app.recordSpeedState == false
                add_terminal_text(app,'结束记录');
                
            else 
                add_terminal_text(app,'开始速度记录');

            end
            startTime = datetime();
            recordPointNum = 40; %显示最近recordPointNum个点的数据
            speedHistoryT = zeros(1,recordPointNum);
            speedHistoryV = zeros(1,recordPointNum);
            counter = 0; %已经记录点数
            while app.recordSpeedState
                counter = counter + 1;
                thisTime = datetime();
                speedHistoryT(counter) = seconds(thisTime-startTime);
                speedHistoryV(counter) = app.Speed_rpm;
                if ~app.Button_freezeShow.Value
                    if counter == recordPointNum
                        % 腾出20个空的记录点00
                        counter = recordPointNum-20;
                        speedHistoryT(1:counter) = speedHistoryT(21:end);
                        speedHistoryV(1:counter) = speedHistoryV(21:end);
                        speedHistoryT(counter+1:end) = zeros(1,20);
                        speedHistoryV(counter+1:end) = zeros(1,20);
                    end
                    plot(app.UIAxes_speedRecord,speedHistoryT(1:counter),speedHistoryV(1:counter));
                end
                pause(0.2);
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.observeV = uitab(app.TabGroup2);
            app.observeV.Title = '转子测速';
            app.Label = uilabel(app.observeV);
            app.Label.Text = '采集卡状态';
            app.Lamp_card = uilamp(app.observeV);
            app.Switch_card = uiswitch(app.observeV, 'slider');
            app.Switch_card.ValueChangedFcn = createCallbackFcn(app, @Switch_cardValueChanged, true);
            app.Panel_channel1 = uipanel(app.observeV);
            app.Panel_channel1.Title = '测速';
            app.Button_selectAlg = uibuttongroup(app.Panel_channel1);
            app.Button_selectAlg.SelectionChangedFcn = createCallbackFcn(app, @Button_selectAlgSelectionChanged, true);
            app.Button_selectAlg.Title = '选择测速算法';
            app.MButton = uiradiobutton(app.Button_selectAlg);
            app.MButton.Text = 'M算法（高速）';
            app.TButton = uiradiobutton(app.Button_selectAlg);
            app.TButton.Text = 'T算法（低速）';
            app.TButton.Value = true;
            app.Label_5 = uilabel(app.Panel_channel1);
            app.Label_5.Text = '选择通道';
            app.selectChannel = uidropdown(app.Panel_channel1);
            app.selectChannel.Items = {'1', '5'};
            app.selectChannel.ValueChangedFcn = createCallbackFcn(app, @selectChannelValueChanged, true);
            app.selectChannel.Value = '5';
            app.Button_getV = uibutton(app.Panel_channel1, 'state');
            app.Button_getV.ValueChangedFcn = createCallbackFcn(app, @Button_getVValueChanged, true);
            app.Button_getV.Text = '测速';
            app.Label_6 = uilabel(app.Panel_channel1);
            app.Label_6.Text = '齿数';
            app.pulse_num = uieditfield(app.Panel_channel1, 'numeric');
            app.pulse_num.ValueChangedFcn = createCallbackFcn(app, @pulse_numValueChanged, true);
            app.Button_freezeShow = uibutton(app.Panel_channel1, 'state');
            app.Button_freezeShow.ValueChangedFcn = createCallbackFcn(app, @Button_freezeShowValueChanged, true);
            app.Button_freezeShow.Text = '冻结显示';
            app.Panel = uipanel(app.observeV);
            app.Panel.Title = '采样设置';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '采样频率';
            app.samplingRate = uieditfield(app.Panel, 'numeric');
            app.samplingRate.ValueChangedFcn = createCallbackFcn(app, @samplingRateValueChanged, true);
            app.samplingRate.Limits = [0 Inf];
            app.samplingRate.ValueDisplayFormat = '%.0f';
            app.EditField_2Label_2 = uilabel(app.Panel);
            app.EditField_2Label_2.Text = '采样长度';
            app.samplingExtent = uieditfield(app.Panel, 'numeric');
            app.samplingExtent.ValueChangedFcn = createCallbackFcn(app, @samplingExtentValueChanged, true);
            app.samplingExtent.Limits = [0 Inf];
            app.samplingExtent.ValueDisplayFormat = '%.0f';
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '开环控制';
            app.Label_7 = uilabel(app.Tab_3);
            app.Label_7.Text = '高电平时间';
            app.VohTime = uieditfield(app.Tab_3, 'numeric');
            app.VohTime.ValueChangedFcn = createCallbackFcn(app, @VohTimeValueChanged, true);
            app.VohTime.Limits = [800 2200];
            app.VohTime.ValueDisplayFormat = '%.0f';
            app.VohTime.Value = 1500;
            app.usLabel = uilabel(app.Tab_3);
            app.usLabel.Text = '*1us';
            app.Button_selectCalibrationMethod = uibuttongroup(app.Tab_3);
            app.Button_selectCalibrationMethod.SelectionChangedFcn = createCallbackFcn(app, @Button_selectCalibrationMethodSelectionChanged, true);
            app.Button_selectCalibrationMethod.Title = '标定';
            app.Button_CO = uiradiobutton(app.Button_selectCalibrationMethod);
            app.Button_CO.Text = '自动标定';
            app.Button_CO.Value = true;
            app.Button_BP = uiradiobutton(app.Button_selectCalibrationMethod);
            app.Button_BP.Text = 'BP网络拟合';
            app.Button_DS = uiradiobutton(app.Button_selectCalibrationMethod);
            app.Button_DS.Text = '数据集训练';
            app.startTrain = uibutton(app.Tab_3, 'state');
            app.startTrain.ValueChangedFcn = createCallbackFcn(app, @startTrainValueChanged, true);
            app.startTrain.Text = 'Train';
            app.Label_8 = uilabel(app.Tab_3);
            app.Label_8.Text = '控制转速';
            app.targetSpeed_openLoop = uieditfield(app.Tab_3, 'numeric');
            app.targetSpeed_openLoop.ValueChangedFcn = createCallbackFcn(app, @targetSpeed_openLoopValueChanged, true);
            app.rpmLabel = uilabel(app.Tab_3);
            app.rpmLabel.Text = 'rpm';
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '闭环控制';
            app.Button_selectPidMethod = uibuttongroup(app.Tab_4);
            app.Button_selectPidMethod.SelectionChangedFcn = createCallbackFcn(app, @Button_selectPidMethodSelectionChanged, true);
            app.Button_selectPidMethod.Title = '自整定';
            app.Button_NO = uiradiobutton(app.Button_selectPidMethod);
            app.Button_NO.Text = '无';
            app.Button_NO.Value = true;
            app.Button_FZ = uiradiobutton(app.Button_selectPidMethod);
            app.Button_FZ.Text = '模糊控制';
            app.BPButton = uiradiobutton(app.Button_selectPidMethod);
            app.BPButton.Text = 'BP神经网络';
            app.KpEditFieldLabel = uilabel(app.Tab_4);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.Tab_4, 'numeric');
            app.KpEditField.ValueChangedFcn = createCallbackFcn(app, @KpEditFieldValueChanged, true);
            app.KiEditFieldLabel = uilabel(app.Tab_4);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.Tab_4, 'numeric');
            app.KiEditField.ValueChangedFcn = createCallbackFcn(app, @KiEditFieldValueChanged, true);
            app.KdEditFieldLabel = uilabel(app.Tab_4);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.Tab_4, 'numeric');
            app.KdEditField.ValueChangedFcn = createCallbackFcn(app, @KdEditFieldValueChanged, true);
            app.Label_9 = uilabel(app.Tab_4);
            app.Label_9.Text = '控制转速';
            app.targetSpeed_closeLoop = uieditfield(app.Tab_4, 'numeric');
            app.targetSpeed_closeLoop.ValueChangedFcn = createCallbackFcn(app, @targetSpeed_closeLoopValueChanged, true);
            app.rpmLabel_2 = uilabel(app.Tab_4);
            app.rpmLabel_2.Text = 'rpm';
            app.startCloseLoopControl = uibutton(app.Tab_4, 'state');
            app.startCloseLoopControl.ValueChangedFcn = createCallbackFcn(app, @startCloseLoopControlValueChanged, true);
            app.startCloseLoopControl.Text = '控制';
            app.UIAxes_sub = uiaxes(app.UIFigure);
            title(app.UIAxes_sub, '标定')
            xlabel(app.UIAxes_sub, 'Voh')
            ylabel(app.UIAxes_sub, 'Speed')
            app.UIAxes_main = uiaxes(app.UIFigure);
            title(app.UIAxes_main, '信号采集')
            xlabel(app.UIAxes_main, 't')
            ylabel(app.UIAxes_main, 'A')
            app.TerminalTextAreaLabel = uilabel(app.UIFigure);
            app.TerminalTextAreaLabel.Text = 'Terminal';
            app.TerminalTextArea = uitextarea(app.UIFigure);
            app.TerminalTextArea.Editable = 'off';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '转速';
            app.V_Hz = uieditfield(app.UIFigure, 'numeric');
            app.V_Hz.Editable = 'off';
            app.rpmEditFieldLabel = uilabel(app.UIFigure);
            app.rpmEditFieldLabel.Text = 'rpm';
            app.V_rpm = uieditfield(app.UIFigure, 'numeric');
            app.V_rpm.Editable = 'off';
            app.UIAxes_speedRecord = uiaxes(app.UIFigure);
            title(app.UIAxes_speedRecord, '转速记录')
            xlabel(app.UIAxes_speedRecord, 't')
            ylabel(app.UIAxes_speedRecord, 'speed')
            app.Button_stop = uibutton(app.UIFigure, 'push');
            app.Button_stop.ButtonPushedFcn = createCallbackFcn(app, @Button_stopPushed, true);
            app.Button_stop.Text = '转子停转';
            app.Button_beginRecordSpeed = uibutton(app.UIFigure, 'state');
            app.Button_beginRecordSpeed.ValueChangedFcn = createCallbackFcn(app, @Button_beginRecordSpeedValueChanged, true);
            app.Button_beginRecordSpeed.Text = '开始记录';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LiCongYYDS

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end